classdef appFINTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FINTERUIFigure        matlab.ui.Figure
        IngresardatosMenu     matlab.ui.container.Menu
        InterpolarMenu        matlab.ui.container.Menu
        LagrangeMenu          matlab.ui.container.Menu
        NewtonGregoryMenu     matlab.ui.container.Menu
        ProgresivoMenu        matlab.ui.container.Menu
        RegresivoMenu         matlab.ui.container.Menu
        PasosdeclculoMenu     matlab.ui.container.Menu
        EspecializarPkMenu    matlab.ui.container.Menu
        ModificarvaloresMenu  matlab.ui.container.Menu
        FinalizarMenu         matlab.ui.container.Menu
    end


    methods (Access = private)

        % Menu selected function: InterpolarMenu
        function InterpolarMenuSelected(app, event)
            
        end

        % Menu selected function: FinalizarMenu
        function FinalizarMenuSelected(app, event)
            myicon = imread('FINTER.png');
            app.FINTERUIFigure.Visible = 'off';
            msgbox('Gracias por utilizar FINTER','Success','custom',myicon);
            app.delete;
        end

        % Menu selected function: LagrangeMenu
        function LagrangeMenuSelected(app, event)
            app.FINTERUIFigure.Visible = 'off';
            waitfor(appLagrange(false));
            app.FINTERUIFigure.Visible = 'on';
        end

        % Menu selected function: IngresardatosMenu
        function IngresardatosMenuSelected(app, event)
            
        end

        % Menu selected function: ModificarvaloresMenu
        function ModificarvaloresMenuSelected(app, event)
            app.FINTERUIFigure.Visible = 'off';
            waitfor(appLagrange(true));
            app.FINTERUIFigure.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FINTERUIFigure = uifigure;
            app.FINTERUIFigure.Name = 'FINTER';
            app.IngresardatosMenu = uimenu(app.FINTERUIFigure);
            app.IngresardatosMenu.MenuSelectedFcn = createCallbackFcn(app, @IngresardatosMenuSelected, true);
            app.IngresardatosMenu.Text = 'Ingresar datos';
            app.InterpolarMenu = uimenu(app.IngresardatosMenu);
            app.InterpolarMenu.MenuSelectedFcn = createCallbackFcn(app, @InterpolarMenuSelected, true);
            app.InterpolarMenu.Text = 'Interpolar';
            app.LagrangeMenu = uimenu(app.InterpolarMenu);
            app.LagrangeMenu.MenuSelectedFcn = createCallbackFcn(app, @LagrangeMenuSelected, true);
            app.LagrangeMenu.Text = 'Lagrange';
            app.NewtonGregoryMenu = uimenu(app.InterpolarMenu);
            app.NewtonGregoryMenu.Text = 'Newton Gregory';
            app.ProgresivoMenu = uimenu(app.NewtonGregoryMenu);
            app.ProgresivoMenu.Text = 'Progresivo';
            app.RegresivoMenu = uimenu(app.NewtonGregoryMenu);
            app.RegresivoMenu.Text = 'Regresivo';
            app.PasosdeclculoMenu = uimenu(app.FINTERUIFigure);
            app.PasosdeclculoMenu.Text = 'Pasos de cálculo';
            app.EspecializarPkMenu = uimenu(app.FINTERUIFigure);
            app.EspecializarPkMenu.Text = 'Especializar P(k)';
            app.ModificarvaloresMenu = uimenu(app.FINTERUIFigure);
            app.ModificarvaloresMenu.MenuSelectedFcn = createCallbackFcn(app, @ModificarvaloresMenuSelected, true);
            app.ModificarvaloresMenu.Text = 'Modificar valores';
            app.FinalizarMenu = uimenu(app.FINTERUIFigure);
            app.FinalizarMenu.MenuSelectedFcn = createCallbackFcn(app, @FinalizarMenuSelected, true);
            app.FinalizarMenu.Text = 'Finalizar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appFINTER

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FINTERUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FINTERUIFigure)
        end
    end
end