classdef appLagrange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LagrangeUIFigure         matlab.ui.Figure
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        AgregarButton            matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        CalcularPolinomioButton  matlab.ui.control.Button
        PxEditFieldLabel         matlab.ui.control.Label
        PxEditField              matlab.ui.control.EditField
        VolveralMenButton        matlab.ui.control.Button
        EliminarButton           matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modificar)
            if modificar
                global x y;
                app.UITable.Data = [x,y];
                app.UITable.ColumnEditable = true;
                app.EliminarButton.Visible = true;
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data;app.XEditField.Value, app.YEditField.Value];
        end

        % Button pushed function: VolveralMenButton
        function VolveralMenButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: CalcularPolinomioButton
        function CalcularPolinomioButtonPushed(app, event)
            if isempty(app.UITable.Data)
                msgbox('Debe ingresar al menos un punto','Error','error');                
            else
                global x y;
                x = app.UITable.Data(:,1);
                y = app.UITable.Data(:,2);
                if length(x) > 1
                    app.PxEditField.Value = char(Lagrange(x,y));
                else
                    app.PxEditField.Value = num2str(y);
                end
            end
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            global indices;
            app.UITable.Data(indices(1),:) = [];
            app.EliminarButton.Enable = false;            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global indices;
            indices = event.Indices;
            app.EliminarButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LagrangeUIFigure = uifigure('Visible', 'off');
            app.LagrangeUIFigure.Name = 'Lagrange';
            app.XEditFieldLabel = uilabel(app.LagrangeUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LagrangeUIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.LagrangeUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LagrangeUIFigure, 'numeric');
            app.AgregarButton = uibutton(app.LagrangeUIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.UITable = uitable(app.LagrangeUIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalcularPolinomioButton = uibutton(app.LagrangeUIFigure, 'push');
            app.CalcularPolinomioButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularPolinomioButtonPushed, true);
            app.CalcularPolinomioButton.Text = 'Calcular Polinomio';
            app.PxEditFieldLabel = uilabel(app.LagrangeUIFigure);
            app.PxEditFieldLabel.Text = 'P(x) = ';
            app.PxEditField = uieditfield(app.LagrangeUIFigure, 'text');
            app.PxEditField.Editable = 'off';
            app.PxEditField.Enable = 'off';
            app.VolveralMenButton = uibutton(app.LagrangeUIFigure, 'push');
            app.VolveralMenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralMenButtonPushed, true);
            app.VolveralMenButton.Text = 'Volver al Menú';
            app.EliminarButton = uibutton(app.LagrangeUIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Enable = 'off';
            app.EliminarButton.Visible = 'off';
            app.EliminarButton.Text = 'Eliminar';
            % Show the figure after all components are created
            app.LagrangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appLagrange(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LagrangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LagrangeUIFigure)
        end
    end
end